classdef app3_1_mod1_pupil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        VelocidadDropDown       matlab.ui.control.DropDown
        VelocidadDropDownLabel  matlab.ui.control.Label
        DetenerButton           matlab.ui.control.Button
        Image2                  matlab.ui.control.Image
        IniciarButton           matlab.ui.control.Button
        numeroEditField         matlab.ui.control.NumericEditField
        TiempoSlider            matlab.ui.control.Slider
        TiempoSliderLabel       matlab.ui.control.Label
        SeleccionarButton       matlab.ui.control.Button
        CerrarButton            matlab.ui.control.Button
        ListBox_2               matlab.ui.control.ListBox
        CargarButton            matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        TextArea                matlab.ui.control.TextArea
        AbrirButton             matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        SelecciondedatosLabel   matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        directorio3_1 = '/home/osvaldo13576/Documentos/Servicio/pupil_data/2022_06_03/000';
        datos_csv% Variables.....
        verdad = "true";
        falso = "false";
        file_world_mp4 = 'world.mp4';
        file_eye0_mp4 = 'eye0.mp4';
        file_world_timestamps_csv = 'world_timestamps.csv';
        file_eye0_timestamps_csv = 'eye0_timestamps.csv';
        file_pupil_positions_csv = 'pupil_positions.csv';
        file_blinks_csv = 'blinks.csv';
        file_info_player_json = 'info.player.json';
        dir_exports = 'exports';
        %variables
        file_world_mp4_var;
        file_eye0_mp4_var;
        file_world_timestamps_csv_var;
        file_eye0_timestamps_csv_var;
        file_pupil_positions_csv_var;
        file_blinks_csv_var;
        % otros
        romper_for = 0;
        frame_world;
        velocidad = 1;
        vector_tiempo;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = app.directorio3_1;
            app.EditField.Editable = 0;
            app.ListBox.Enable = 0;
            app.ListBox_2.Enable = 0;
            app.CargarButton.Enable = 0;
            app.TextArea.Value = "Esperando archivos.";
            app.TiempoSlider.Limits = [1,999];
            app.TiempoSlider.Enable = 0;
            app.UIAxes.Visible = 1;
            app.IniciarButton.Enable = 0;
            app.DetenerButton.Enable = 0;
            app.numeroEditField.Editable = 0;
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            % establecemos el valor del directorio
            directorio = uigetdir;
            if directorio == 0
                app.EditField.Value = app.directorio3_1;
            else
                app.EditField.Value = directorio;
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            %dado un directorio listamos las carpetas precentes en e'l
            %verificamos si es un direc vacio
            if is_an_empty(app.EditField.Value) == 1
                app.TextArea.Value = "Directorio vacio.";
            else
                 %buscamos la existencia del archivo info.player.json
                 %para determinar si es un directorio valido
                 if isarchivo(app.EditField.Value,app.file_info_player_json) == 1 && ...
                    isafolder(app.EditField.Value,app.dir_exports) == 1
                    directorio2 = fullfile(app.EditField.Value,app.dir_exports);
                    
                    if is_an_empty(directorio2) == 0
                        datosjson = readjson(fullfile(app.EditField.Value,app.file_info_player_json));
                        duracion_exp_min = datosjson.duration_s/60;
                        duracion_exp_min_char = sprintf('%.2f',duracion_exp_min);
                        start_time_synced_s = datosjson.start_time_synced_s;
                        start_time_system_s = datosjson.start_time_system_s;
                        fecha = convert_timestamp(start_time_system_s-start_time_synced_s);
                        app.TextArea.Value = "Archivo info.player.json entontrado."+newline+...
                        "Directorio exports no vacio, carpetas cargadas."+...
                        newline+"Duracion de la grabacion: "+convertCharsToStrings(duracion_exp_min_char)+" minutos"...
                        +newline+"Inicio del experimento: " + convertCharsToStrings(fecha);
                        app.ListBox.Items = listaFolders(directorio2);
                        app.ListBox.Enable = 1; 

                    else
                        app.TextArea.Value = "Directorio exports vacio";
                        app.ListBox.Enable = 0;
                    end
                 else
                     app.ListBox.Enable = 0;
                     app.TextArea.Value = "Directorio incompatible";

                end
                    
            end
           
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %
            directorio = fullfile(app.EditField.Value,app.dir_exports,app.ListBox.Value);
            app.ListBox_2.Items = listaArchivos(directorio,'*.csv');
            if length(app.ListBox_2.Items)>1
                app.ListBox_2.Enable = 1;
                if isarchivo(directorio,app.file_eye0_timestamps_csv) == 1 && ...
                        isarchivo(directorio,app.file_world_timestamps_csv)
                    %app.TiempoSlider.Enable = 1;
                    numdatos_world= num_csv(fullfile(directorio,app.file_world_timestamps_csv));
                    numdatos_eye0 = num_csv(fullfile(directorio,app.file_eye0_timestamps_csv));
                    app.TiempoSlider.Limits = [1,numdatos_eye0*2];
                    app.CargarButton.Enable = 1;
                    %app.IniciarButton.Enable = 1;
                    app.TextArea.Value = "Datos encontrados (eye0_timestamps)  = "+  int2str(numdatos_eye0)...
                        +newline+        "Datos encontrados (world_timestamps) =" + int2str(numdatos_world);
                else
                    app.TiempoSlider.Enable = 0;
                    app.IniciarButton.Enable = 0;
                    app.CargarButton.Enable = 0;
                    app.TextArea.Value = "Faltan archivos.";
                end
            else
                app.TiempoSlider.Enable = 0;
                app.ListBox_2.Enable = 0;
                app.TiempoSlider.Enable = 0;
                app.IniciarButton.Enable = 0;
                app.CargarButton.Enable = 0;
            end
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            %cargamos el archivo: 
            %   (1)'world.mp4'             &
            %   (2)'eye0.mp4'              &
            %   (3)'world_timestamps.csv'  &
            %   (4)'eye0_timestamps.csv'   &
            %   (5)'pupil_positions.csv'   &
            %   (6)'blinks.csv'            &
 
            directorio_csv = fullfile(app.EditField.Value,app.dir_exports,app.ListBox.Value);
            %archivos_ = {app.file_world_mp4,app.file_eye0_mp4,app.file_world_timestamps_csv,...
            %     app.file_eye0_timestamps_csv,app.file_pupil_positions_csv,app.file_blinks_csv}';
            %creamos un vector elementos char con los directorios de los
            %archivos
            %archivos_ = fullfile(directorio_csv,archivos_);
            %comprobamos si existen todos y lo mostramos; se cargan
            texto = "Cargando archivos...";
            app.TextArea.Value = texto;
            pause(1);
           %% (1)
           if isarchivo(directorio_csv,app.file_world_mp4) == 1
                texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_world_mp4)+")="+app.verdad;
                app.TextArea.Value = texto;
                app.file_world_mp4_var = VideoReader(fullfile(directorio_csv,app.file_world_mp4));
                texto = texto + " (cargado)";
                app.TextArea.Value = texto;
           else
               texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_world_mp4)+")="+app.falso;
                app.TextArea.Value = texto;
           end
           pause(1)
           %% (2)
           if isarchivo(directorio_csv,app.file_eye0_mp4) == 1
                texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_eye0_mp4)+")="+app.verdad;
                app.TextArea.Value = texto;
                app.file_eye0_mp4_var = VideoReader(fullfile(directorio_csv,app.file_eye0_mp4));
                texto = texto + " (cargado)";
                app.TextArea.Value = texto;
           else
               texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_world_mp4)+")="+app.falso;
                app.TextArea.Value = texto;
           end    
           %% (3)
           if isarchivo(directorio_csv,app.file_world_timestamps_csv) == 1
                texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_world_timestamps_csv)+")="+app.verdad;
                app.TextArea.Value = texto;
                num_datos = num_csv(fullfile(directorio_csv,app.file_world_timestamps_csv));
                app.file_world_timestamps_csv_var = zeros(num_datos,1);
                for k = 1:num_datos
                    app.file_world_timestamps_csv_var(k) = k;
                end
                texto = texto + " (cargado)";
                app.TextArea.Value = texto;
           else
               texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_world_timestamps_csv)+")="+app.falso;
                app.TextArea.Value = texto;
           end   
           %% (4)
           if isarchivo(directorio_csv,app.file_eye0_timestamps_csv) == 1
                texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_eye0_timestamps_csv)+")="+app.verdad;
                app.TextArea.Value = texto;
                num_datos = num_csv(fullfile(directorio_csv,app.file_eye0_timestamps_csv));
                app.file_eye0_timestamps_csv_var = zeros(num_datos,1);
                for k = 1:num_datos
                    app.file_eye0_timestamps_csv_var(2*(k-1)+1)= k;
                    app.file_eye0_timestamps_csv_var(2*(k-1)+2)= k;
                end
                texto = texto + " (cargado)";
                app.TextArea.Value = texto;
           else
               texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_eye0_timestamps_csv)+")="+app.falso;
                app.TextArea.Value = texto;
           end  
           %% (5)
           if isarchivo(directorio_csv,app.file_pupil_positions_csv) == 1
                texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_pupil_positions_csv)+")="+app.verdad;
                app.TextArea.Value = texto;
                app.file_pupil_positions_csv_var = readtable(fullfile(directorio_csv,app.file_pupil_positions_csv));
                app.frame_world = app.file_pupil_positions_csv_var.world_index+1;
                app.vector_tiempo = app.file_pupil_positions_csv_var.pupil_timestamp...
                                    -app.file_pupil_positions_csv_var.pupil_timestamp(1);
                texto = texto + " (cargado)";
                app.TextArea.Value = texto;
           else
               texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_pupil_positions_csv)+")="+app.falso;
                app.TextArea.Value = texto;
           end  
           %% (6)
            if isarchivo(directorio_csv,app.file_blinks_csv) == 1
                texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_blinks_csv)+")="+app.verdad;
                app.TextArea.Value = texto;
                %cargamos los datos blinks de manera binaria
                app.file_blinks_csv_var =...
                    csv_to_binary_blinks(fullfile(directorio_csv,app.file_blinks_csv),...
                   num_csv(fullfile(directorio_csv,app.file_world_timestamps_csv)) );      
                texto = texto + " (cargado)";
                app.TextArea.Value = texto;
                
                
           else
               texto = texto + newline + "existencia("...
                    +convertCharsToStrings(app.file_blinks_csv)+")="+app.falso;
                app.TextArea.Value = texto;
            end  
            %%
            app.IniciarButton.Enable = 1;
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            
            app.romper_for = 0;
            app.DetenerButton.Enable  = 1;
            app.CerrarButton.Enable = 0;
            num_datos = length(app.file_eye0_timestamps_csv_var*2);
            %app.UIAxes.XLim = [(contador*delay/1000)-app.VentanaEditField.Value (contador*delay/1000)+0];
            %plot(app.UIAxes,tiempo(1:contador),voltaje(1:contador))
            plot(app.UIAxes,app.vector_tiempo,app.file_pupil_positions_csv_var.diameter)
            for k = 1:num_datos
                contador = k*app.velocidad;
                if contador < num_datos
                        app.numeroEditField.Value = contador;
                        app.TiempoSlider.Value = contador;
                        app.Image.ImageSource = read(app.file_world_mp4_var,app.frame_world(contador));
                        app.Image2.ImageSource = flipud(read(app.file_eye0_mp4_var,app.file_eye0_timestamps_csv_var(contador)));
                        app.UIAxes.XLim = [app.vector_tiempo(contador)-1,...
                            app.vector_tiempo(contador)+1];
                        %pause(1/(360));
                        drawnow limitrate     
                else
                    break
                end
                if app.romper_for == 1
                    break
                end
                
            end 
            app.CerrarButton.Enable = 1;
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
             
             app.delete;
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.romper_for = 1;
            app.DetenerButton.Enable = 0;
        end

        % Value changed function: VelocidadDropDown
        function VelocidadDropDownValueChanged(app, event)
            
            app.TextArea.Value = app.VelocidadDropDown.Value;
            if app.VelocidadDropDown.Value == "x1"
                app.velocidad = 1;
            end
            if app.VelocidadDropDown.Value == "x2"
                app.velocidad = 2;
            end
            if app.VelocidadDropDown.Value == "x5"
                app.velocidad = 5;
            end
            if app.VelocidadDropDown.Value == "x10"
                app.velocidad = 10;
            end
            if app.VelocidadDropDown.Value == "x20"
                app.velocidad = 20;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diametro de la pupila')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Diametro (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 40];
            app.Image = uiimage(app.UIFigure);
            app.SelecciondedatosLabel = uilabel(app.UIFigure);
            app.SelecciondedatosLabel.Text = 'Seleccion de datos';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.TextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Carpetas'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Carpetas';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'Archivos en la carpeta Exports'};
            app.ListBox_2.Value = 'Archivos en la carpeta Exports';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.TiempoSliderLabel = uilabel(app.UIFigure);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.UIFigure);
            app.TiempoSlider.MajorTicks = [];
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.numeroEditField = uieditfield(app.UIFigure, 'numeric');
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.Image2 = uiimage(app.UIFigure);
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.VelocidadDropDownLabel = uilabel(app.UIFigure);
            app.VelocidadDropDownLabel.Text = 'Velocidad';
            app.VelocidadDropDown = uidropdown(app.UIFigure);
            app.VelocidadDropDown.Items = {'x1', 'x2', 'x5', 'x10', 'x20'};
            app.VelocidadDropDown.ValueChangedFcn = createCallbackFcn(app, @VelocidadDropDownValueChanged, true);
            app.VelocidadDropDown.Value = 'x1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3_1_mod1_pupil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end